classdef FizykaDwieCzastkiOXOY < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DwieprzesuwajcesiczstkiAiBUIFigure  matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        WcinijbynieczyciwykresupodczassymulacjiButton  matlab.ui.control.StateButton
        Label                           matlab.ui.control.Label
        Gauge                           matlab.ui.control.LinearGauge
        TabGroup                        matlab.ui.container.TabGroup
        DanewykresuTab                  matlab.ui.container.Tab
        NajmniejszyWektorLabel          matlab.ui.control.Label
        OdwiedaneponiejButton           matlab.ui.control.Button
        DanewykresuLabel                matlab.ui.control.Label
        UstawieniaoglneTab              matlab.ui.container.Tab
        AXdomylnie3Label                matlab.ui.control.Label
        AYdomylnie0Label                matlab.ui.control.Label
        BXdomylnie0Label                matlab.ui.control.Label
        BYdomylnie3Label                matlab.ui.control.Label
        VAdomylnie2Label                matlab.ui.control.Label
        VBdomylnie3Label                matlab.ui.control.Label
        PrdkoczstkiAOXEditFieldLabel    matlab.ui.control.Label
        PrdkoczstkiAOXEditField         matlab.ui.control.NumericEditField
        PrdkoczstkiBOYEditFieldLabel    matlab.ui.control.Label
        PrdkoczstkiBOYEditField         matlab.ui.control.NumericEditField
        WsprzdnaXczstkiAEditFieldLabel  matlab.ui.control.Label
        WsprzdnaXczstkiAEditField       matlab.ui.control.NumericEditField
        WsprzdnaYczstkiAEditFieldLabel  matlab.ui.control.Label
        WsprzdnaYczstkiAEditField       matlab.ui.control.NumericEditField
        WsprzdnaXczstkiBEditFieldLabel  matlab.ui.control.Label
        WsprzdnaXczstkiBEditField       matlab.ui.control.NumericEditField
        WsprzdnaYczstkiBEditFieldLabel  matlab.ui.control.Label
        WsprzdnaYczstkiBEditField       matlab.ui.control.NumericEditField
        CzassymulacjiwsekundachEditFieldLabel  matlab.ui.control.Label
        CzassymulacjiwsekundachEditField  matlab.ui.control.NumericEditField
        Tdomylnie5sLabel                matlab.ui.control.Label
        UstawieniawywietlaniawykresuTab  matlab.ui.container.Tab
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        UstawzakresButton               matlab.ui.control.Button
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        ZakreswykresuXEditField         matlab.ui.control.NumericEditField
        ZakreswykresuXEditFieldLabel    matlab.ui.control.Label
        Domylnyzakres48413Button        matlab.ui.control.Button
        BrakzakresuustawianyautomatycznieButton  matlab.ui.control.Button
        NarzdziarysowaniaTab            matlab.ui.container.Tab
        NarysujrcznieobieczstkiowpisanychwsprzdnychXYButton  matlab.ui.control.Button
        NarysujrczniewektorpomidzyczstkamiwpunkciepocztkowymButton  matlab.ui.control.Button
        NarysujnajkrtszywektorZawszeodwieButton  matlab.ui.control.Button
        SymulacjaTab                    matlab.ui.container.Tab
        Wczsymulacj25krokwButton        matlab.ui.control.Button
        Wczsymulacj50krokwButton        matlab.ui.control.Button
        Wczsymulacj100krokwButton       matlab.ui.control.Button
        RczniewyczywykresButton         matlab.ui.control.Button
        KnobLabel                       matlab.ui.control.Label
        Knob                            matlab.ui.control.Knob
        RczniewpiszczasEditFieldLabel   matlab.ui.control.Label
        RczniewpiszczasEditField        matlab.ui.control.NumericEditField
        Obecnyczas0sLabel               matlab.ui.control.Label
    end


    properties (Access = public)
        najkrotszywektor = [ 0, 0, 0, 0 ];
    end

    methods (Access = private)

        % Code that executes after component creation
        function Inicjalizacja(app)
            axis(app.UIAxes,[-4 8 -4 13]);
            OdswiezDaneWektora(app);
            PostepSymulacji(app);
        end

        % Button pushed function: 
        % BrakzakresuustawianyautomatycznieButton
        function BrakZakresuWykesu(app, event)
            xlim(app.UIAxes, [-inf inf]);
            ylim(app.UIAxes, [-inf inf]);
        end

        % Button pushed function: Domylnyzakres48413Button
        function DomyslnyZakresWykresu(app, event)
            axis(app.UIAxes,[-4 8 -4 13]);
        end

        % Value changed function: Knob
        function GalkaZmiana(app, event)
            app.Gauge.Value = event.Value;
            PostepSymulacji(app);
        end

        % Button pushed function: 
        % NarysujnajkrtszywektorZawszeodwieButton
        function NarysujNajkrotszyWektor(app, event)
            if app.WcinijbynieczyciwykresupodczassymulacjiButton.Value == 0
                cla(app.UIAxes);
            end
            hold(app.UIAxes,'on');
            scatter(app.UIAxes, app.najkrotszywektor(1), app.najkrotszywektor(2),[],'b');
            scatter(app.UIAxes, app.najkrotszywektor(3), app.najkrotszywektor(4),[],'r');
            x = [ app.najkrotszywektor(1) , app.najkrotszywektor(3) ];
            y = [ app.najkrotszywektor(2) , app.najkrotszywektor(4) ];
            line(app.UIAxes,x,y);
        end

        % Button pushed function: 
        % NarysujrcznieobieczstkiowpisanychwsprzdnychXYButton
        function NarysujObieCzastki(app, event)
            hold(app.UIAxes,'on');
            scatter(app.UIAxes,app.WsprzdnaXczstkiAEditField.Value,app.WsprzdnaYczstkiAEditField.Value,[],'b');
            scatter(app.UIAxes,app.WsprzdnaXczstkiBEditField.Value,app.WsprzdnaYczstkiBEditField.Value,[],'r');
        end

        % Button pushed function: 
        % NarysujrczniewektorpomidzyczstkamiwpunkciepocztkowymButton
        function NarysujWektor(app, event)
            x = [ app.WsprzdnaYczstkiBEditField.Value , app.WsprzdnaYczstkiAEditField.Value ];
            y = [ app.WsprzdnaXczstkiBEditField.Value , app.WsprzdnaXczstkiAEditField.Value ];
            line(app.UIAxes,y,x);
        end

        % Button pushed function: OdwiedaneponiejButton
        function OdswiezDaneWektora(app, event)
            AX = app.WsprzdnaXczstkiAEditField.Value;
            BY = app.WsprzdnaYczstkiBEditField.Value;
            AY = app.WsprzdnaYczstkiAEditField.Value;
            BX = app.WsprzdnaXczstkiBEditField.Value;
            
            ax = AX;
            ay = AY;
            bx = BX;
            by = BY;
            va = app.PrdkoczstkiAOXEditField.Value;
            vb = app.PrdkoczstkiBOYEditField.Value;
            
            min = app.Knob.Limits(1);
            max = app.Knob.Limits(2);
            przedzial = (max-min)/5000;
            najkrotszy = sqrt( (BX - AX)^2 + (BY - AY)^2 );
            suwak = 0;
            app.najkrotszywektor = [ AX AY BX BY ];
            
            for i = min:przedzial:max
                AX = ax + i * va;
                BY = by + i * vb;
                AX2 = ax + (i+przedzial) * va;
                BY2 = by + (i+przedzial) * vb;
                
                dlugosc1 = sqrt( (BX - AX)^2 + (BY - AY)^2 );
                dlugosc2 = sqrt( (BX - AX2)^2 + (BY2 - AY)^2 );
                
                if dlugosc1 > dlugosc2
                    najkrotszy = dlugosc2;
                    suwak = i;
                    app.najkrotszywektor = [ AX AY BX BY ];
                end
            end
            naj = app.najkrotszywektor;
            A = ['Długość najkrótszego wektora = ' num2str(najkrotszy) ];
            B = ['Czas dla najkrótszego wektora = ' num2str(suwak)];
            C = ['Koordynaty A(' num2str(naj(1)) ', ' num2str(naj(2)) ') i B(' num2str(naj(3)) ', ' num2str(naj(4)) ')'];
            D = ['Policzono dla danych początkowych = A(' num2str(ax) ', ' num2str(ay) ') i B(' num2str(bx) ', ' num2str(by) ')'];
            E = ['oraz prędkości A = ' num2str(va) ' i B = ' num2str(vb)];
            app.NajmniejszyWektorLabel.Text = sprintf('%s\n%s\n%s\n\n%s\n%s', A , B , C , D , E );
        end

        % Callback function
        function PostepSymulacji(app, event)
            AX = app.WsprzdnaXczstkiAEditField.Value + app.Knob.Value * app.PrdkoczstkiAOXEditField.Value;
            AY = app.WsprzdnaYczstkiAEditField.Value;
            BX = app.WsprzdnaXczstkiBEditField.Value;
            BY = app.WsprzdnaYczstkiBEditField.Value + app.Knob.Value * app.PrdkoczstkiBOYEditField.Value;
            if app.WcinijbynieczyciwykresupodczassymulacjiButton.Value == 0
                cla(app.UIAxes);
            end
            hold(app.UIAxes,'on');
            scatter(app.UIAxes, AX, AY,[],'b');
            scatter(app.UIAxes, BX, BY,[],'r');
            
            x = [ AX , BX ];
            y = [ AY , BY ];
            line(app.UIAxes,x,y);
            
            wektorAB = sqrt( (BX - AX)^2 + (BY - AY)^2 );
            A = ['Koordynaty A = (' num2str(AX) ', ' num2str(BX) ')'];
            B = ['Koordynaty B = (' num2str(AY) ', ' num2str(BY) ')'];
            C = ['Obecna długość wektora AB = ' num2str(wektorAB) ];
            D = ['Obecny moment czasu = ' num2str(app.Knob.Value)];
            app.DanewykresuLabel.Text = sprintf('%s\n%s\n%s\n%s\n%s', A , B , C , '' , D );
        end

        % Value changed function: RczniewpiszczasEditField
        function RecznieUstawCzas(app, event)
            app.Knob.Value = app.RczniewpiszczasEditField.Value;
            PostepSymulacji(app);
            app.Obecnyczas0sLabel.Text = ['Obecny czas: ' num2str(app.Knob.Value) ' s' ];
        end

        % Button pushed function: UstawzakresButton
        function UstawZakresWykresu(app, event)
            axis(app.UIAxes,[app.ZakreswykresuXEditField.Value app.EditField_2.Value app.YEditField.Value app.EditField_3.Value]);
        end

        % Button pushed function: Wczsymulacj100krokwButton
        function WlaczSymulacje100(app, event)
            cla(app.UIAxes);
            min = app.Knob.Limits(1);
            max = app.Knob.Limits(2);
            przedzial = (max-min)/100;
            for i = min:przedzial:max
                app.Knob.Value = i;
                PostepSymulacji(app);
            end
        end

        % Button pushed function: Wczsymulacj25krokwButton
        function WlaczSymulacje25(app, event)
            cla(app.UIAxes);
            min = app.Knob.Limits(1);
            max = app.Knob.Limits(2);
            przedzial = (max-min)/25;
            for i = min:przedzial:max
                app.Knob.Value = i;
                PostepSymulacji(app);
            end
        end

        % Button pushed function: Wczsymulacj50krokwButton
        function WlaczSymulacje50(app, event)
            cla(app.UIAxes);
            min = app.Knob.Limits(1);
            max = app.Knob.Limits(2);
            przedzial = (max-min)/50;
            for i = min:przedzial:max
                app.Knob.Value = i;
                PostepSymulacji(app);
            end
        end

        % Button pushed function: RczniewyczywykresButton
        function WyczyscWykres(app, event)
            cla(app.UIAxes);
        end

        % Value changing function: Knob
        function ZmianaWartosciGalki(app, event)
            app.Gauge.Value = event.Value;
            PostepSymulacji(app);
            app.Obecnyczas0sLabel.Text = ['Obecny czas: ' num2str(app.Gauge.Value) ' s' ];
        end

        % Value changed function: CzassymulacjiwsekundachEditField
        function ZmienCzas(app, event)
            app.Knob.Limits = [ 0 , app.CzassymulacjiwsekundachEditField.Value ];
            app.Knob.MajorTicks = linspace(0,app.CzassymulacjiwsekundachEditField.Value,11);
            app.RczniewpiszczasEditField.Limits = [ 0 , app.CzassymulacjiwsekundachEditField.Value ];
            app.Gauge.Limits = [ 0 , app.CzassymulacjiwsekundachEditField.Value ];
            app.Gauge.MajorTicks = linspace(0,app.CzassymulacjiwsekundachEditField.Value,11);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DwieprzesuwajcesiczstkiAiBUIFigure = uifigure;
            app.DwieprzesuwajcesiczstkiAiBUIFigure.Name = 'Dwie przesuwające się cząstki A i B';
            app.UIAxes = uiaxes(app.DwieprzesuwajcesiczstkiAiBUIFigure);
            title(app.UIAxes, 'Wykres ruchu cząstek')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.WcinijbynieczyciwykresupodczassymulacjiButton = uibutton(app.DwieprzesuwajcesiczstkiAiBUIFigure, 'state');
            app.WcinijbynieczyciwykresupodczassymulacjiButton.Text = 'Wciśnij, by nie czyścić wykresu podczas symulacji';
            app.Label = uilabel(app.DwieprzesuwajcesiczstkiAiBUIFigure);
            app.Label.Text = '';
            app.Gauge = uigauge(app.DwieprzesuwajcesiczstkiAiBUIFigure, 'linear');
            app.Gauge.Limits = [0 5];
            app.Gauge.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.Gauge.MinorTicks = [];
            app.TabGroup = uitabgroup(app.DwieprzesuwajcesiczstkiAiBUIFigure);
            app.DanewykresuTab = uitab(app.TabGroup);
            app.DanewykresuTab.Title = 'Dane wykresu';
            app.NajmniejszyWektorLabel = uilabel(app.DanewykresuTab);
            app.NajmniejszyWektorLabel.Text = 'Najmniejszy Wektor';
            app.OdwiedaneponiejButton = uibutton(app.DanewykresuTab, 'push');
            app.OdwiedaneponiejButton.ButtonPushedFcn = createCallbackFcn(app, @OdswiezDaneWektora, true);
            app.OdwiedaneponiejButton.Text = 'Odśwież dane poniżej:';
            app.DanewykresuLabel = uilabel(app.DanewykresuTab);
            app.DanewykresuLabel.Text = 'Dane wykresu';
            app.UstawieniaoglneTab = uitab(app.TabGroup);
            app.UstawieniaoglneTab.Title = 'Ustawienia ogólne';
            app.AXdomylnie3Label = uilabel(app.UstawieniaoglneTab);
            app.AXdomylnie3Label.Text = 'AX domyślnie = -3';
            app.AYdomylnie0Label = uilabel(app.UstawieniaoglneTab);
            app.AYdomylnie0Label.Text = 'AY domyślnie = 0';
            app.BXdomylnie0Label = uilabel(app.UstawieniaoglneTab);
            app.BXdomylnie0Label.Text = 'BX domyślnie = 0';
            app.BYdomylnie3Label = uilabel(app.UstawieniaoglneTab);
            app.BYdomylnie3Label.Text = 'BY domyślnie = -3';
            app.VAdomylnie2Label = uilabel(app.UstawieniaoglneTab);
            app.VAdomylnie2Label.Text = 'VA domyślnie = 2';
            app.VBdomylnie3Label = uilabel(app.UstawieniaoglneTab);
            app.VBdomylnie3Label.Text = 'VB domyślnie = 3';
            app.PrdkoczstkiAOXEditFieldLabel = uilabel(app.UstawieniaoglneTab);
            app.PrdkoczstkiAOXEditFieldLabel.Text = 'Prędkość cząstki A (OX)';
            app.PrdkoczstkiAOXEditField = uieditfield(app.UstawieniaoglneTab, 'numeric');
            app.PrdkoczstkiAOXEditField.Limits = [0 Inf];
            app.PrdkoczstkiAOXEditField.Value = 2;
            app.PrdkoczstkiBOYEditFieldLabel = uilabel(app.UstawieniaoglneTab);
            app.PrdkoczstkiBOYEditFieldLabel.Text = 'Prędkość cząstki B (OY)';
            app.PrdkoczstkiBOYEditField = uieditfield(app.UstawieniaoglneTab, 'numeric');
            app.PrdkoczstkiBOYEditField.Limits = [0 Inf];
            app.PrdkoczstkiBOYEditField.Value = 3;
            app.WsprzdnaXczstkiAEditFieldLabel = uilabel(app.UstawieniaoglneTab);
            app.WsprzdnaXczstkiAEditFieldLabel.Text = 'Współrzędna X cząstki A';
            app.WsprzdnaXczstkiAEditField = uieditfield(app.UstawieniaoglneTab, 'numeric');
            app.WsprzdnaXczstkiAEditField.Value = -3;
            app.WsprzdnaYczstkiAEditFieldLabel = uilabel(app.UstawieniaoglneTab);
            app.WsprzdnaYczstkiAEditFieldLabel.Text = 'Współrzędna Y cząstki A';
            app.WsprzdnaYczstkiAEditField = uieditfield(app.UstawieniaoglneTab, 'numeric');
            app.WsprzdnaXczstkiBEditFieldLabel = uilabel(app.UstawieniaoglneTab);
            app.WsprzdnaXczstkiBEditFieldLabel.Text = 'Współrzędna X cząstki B';
            app.WsprzdnaXczstkiBEditField = uieditfield(app.UstawieniaoglneTab, 'numeric');
            app.WsprzdnaYczstkiBEditFieldLabel = uilabel(app.UstawieniaoglneTab);
            app.WsprzdnaYczstkiBEditFieldLabel.Text = 'Współrzędna Y cząstki B';
            app.WsprzdnaYczstkiBEditField = uieditfield(app.UstawieniaoglneTab, 'numeric');
            app.WsprzdnaYczstkiBEditField.Value = -3;
            app.CzassymulacjiwsekundachEditFieldLabel = uilabel(app.UstawieniaoglneTab);
            app.CzassymulacjiwsekundachEditFieldLabel.Text = 'Czas symulacji (w sekundach)';
            app.CzassymulacjiwsekundachEditField = uieditfield(app.UstawieniaoglneTab, 'numeric');
            app.CzassymulacjiwsekundachEditField.ValueChangedFcn = createCallbackFcn(app, @ZmienCzas, true);
            app.CzassymulacjiwsekundachEditField.Limits = [0 Inf];
            app.CzassymulacjiwsekundachEditField.Value = 5;
            app.Tdomylnie5sLabel = uilabel(app.UstawieniaoglneTab);
            app.Tdomylnie5sLabel.Text = 'T domyślnie = 5 s';
            app.UstawieniawywietlaniawykresuTab = uitab(app.TabGroup);
            app.UstawieniawywietlaniawykresuTab.Title = 'Ustawienia wyświetlania wykresu';
            app.EditField_2 = uieditfield(app.UstawieniawywietlaniawykresuTab, 'numeric');
            app.EditField_2.Limits = [5 50];
            app.EditField_2.Value = 10;
            app.EditField_3 = uieditfield(app.UstawieniawywietlaniawykresuTab, 'numeric');
            app.EditField_3.Limits = [5 50];
            app.EditField_3.Value = 10;
            app.UstawzakresButton = uibutton(app.UstawieniawywietlaniawykresuTab, 'push');
            app.UstawzakresButton.ButtonPushedFcn = createCallbackFcn(app, @UstawZakresWykresu, true);
            app.UstawzakresButton.Text = 'Ustaw zakres';
            app.YEditFieldLabel = uilabel(app.UstawieniawywietlaniawykresuTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UstawieniawywietlaniawykresuTab, 'numeric');
            app.YEditField.Limits = [-15 15];
            app.YEditField.Value = -10;
            app.ZakreswykresuXEditField = uieditfield(app.UstawieniawywietlaniawykresuTab, 'numeric');
            app.ZakreswykresuXEditField.Limits = [-15 15];
            app.ZakreswykresuXEditField.Value = -10;
            app.ZakreswykresuXEditFieldLabel = uilabel(app.UstawieniawywietlaniawykresuTab);
            app.ZakreswykresuXEditFieldLabel.Text = 'Zakres wykresu: X';
            app.Domylnyzakres48413Button = uibutton(app.UstawieniawywietlaniawykresuTab, 'push');
            app.Domylnyzakres48413Button.ButtonPushedFcn = createCallbackFcn(app, @DomyslnyZakresWykresu, true);
            app.Domylnyzakres48413Button.Text = 'Domyślny zakres [-4 8 -4 13]';
            app.BrakzakresuustawianyautomatycznieButton = uibutton(app.UstawieniawywietlaniawykresuTab, 'push');
            app.BrakzakresuustawianyautomatycznieButton.ButtonPushedFcn = createCallbackFcn(app, @BrakZakresuWykesu, true);
            app.BrakzakresuustawianyautomatycznieButton.Text = 'Brak zakresu (ustawiany automatycznie)';
            app.NarzdziarysowaniaTab = uitab(app.TabGroup);
            app.NarzdziarysowaniaTab.Title = 'Narzędzia rysowania';
            app.NarysujrcznieobieczstkiowpisanychwsprzdnychXYButton = uibutton(app.NarzdziarysowaniaTab, 'push');
            app.NarysujrcznieobieczstkiowpisanychwsprzdnychXYButton.ButtonPushedFcn = createCallbackFcn(app, @NarysujObieCzastki, true);
            app.NarysujrcznieobieczstkiowpisanychwsprzdnychXYButton.Text = {'Narysuj ręcznie obie cząstki'; 'o wpisanych współrzędnych XY'};
            app.NarysujrczniewektorpomidzyczstkamiwpunkciepocztkowymButton = uibutton(app.NarzdziarysowaniaTab, 'push');
            app.NarysujrczniewektorpomidzyczstkamiwpunkciepocztkowymButton.ButtonPushedFcn = createCallbackFcn(app, @NarysujWektor, true);
            app.NarysujrczniewektorpomidzyczstkamiwpunkciepocztkowymButton.Text = {'Narysuj ręcznie wektor pomiędzy cząstkami'; 'o wpisanych współrzędnych XY'};
            app.NarysujnajkrtszywektorZawszeodwieButton = uibutton(app.NarzdziarysowaniaTab, 'push');
            app.NarysujnajkrtszywektorZawszeodwieButton.ButtonPushedFcn = createCallbackFcn(app, @NarysujNajkrotszyWektor, true);
            app.NarysujnajkrtszywektorZawszeodwieButton.Text = 'Narysuj najkrótszy wektor (Zawsze odśwież)';
            app.SymulacjaTab = uitab(app.TabGroup);
            app.SymulacjaTab.Title = 'Symulacja';
            app.Wczsymulacj25krokwButton = uibutton(app.SymulacjaTab, 'push');
            app.Wczsymulacj25krokwButton.ButtonPushedFcn = createCallbackFcn(app, @WlaczSymulacje25, true);
            app.Wczsymulacj25krokwButton.Text = 'Włącz symulację (25 kroków)';
            app.Wczsymulacj50krokwButton = uibutton(app.SymulacjaTab, 'push');
            app.Wczsymulacj50krokwButton.ButtonPushedFcn = createCallbackFcn(app, @WlaczSymulacje50, true);
            app.Wczsymulacj50krokwButton.Text = 'Włącz symulację (50 kroków)';
            app.Wczsymulacj100krokwButton = uibutton(app.SymulacjaTab, 'push');
            app.Wczsymulacj100krokwButton.ButtonPushedFcn = createCallbackFcn(app, @WlaczSymulacje100, true);
            app.Wczsymulacj100krokwButton.Text = 'Włącz symulację (100 kroków)';
            app.RczniewyczywykresButton = uibutton(app.DwieprzesuwajcesiczstkiAiBUIFigure, 'push');
            app.RczniewyczywykresButton.ButtonPushedFcn = createCallbackFcn(app, @WyczyscWykres, true);
            app.RczniewyczywykresButton.Text = 'Ręcznie wyczyść wykres';
            app.KnobLabel = uilabel(app.DwieprzesuwajcesiczstkiAiBUIFigure);
            app.KnobLabel.Text = '';
            app.Knob = uiknob(app.DwieprzesuwajcesiczstkiAiBUIFigure, 'continuous');
            app.Knob.Limits = [0 5];
            app.Knob.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @GalkaZmiana, true);
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @ZmianaWartosciGalki, true);
            app.RczniewpiszczasEditFieldLabel = uilabel(app.DwieprzesuwajcesiczstkiAiBUIFigure);
            app.RczniewpiszczasEditFieldLabel.Text = 'Ręcznie wpisz czas';
            app.RczniewpiszczasEditField = uieditfield(app.DwieprzesuwajcesiczstkiAiBUIFigure, 'numeric');
            app.RczniewpiszczasEditField.ValueChangedFcn = createCallbackFcn(app, @RecznieUstawCzas, true);
            app.RczniewpiszczasEditField.Limits = [0 5];
            app.Obecnyczas0sLabel = uilabel(app.DwieprzesuwajcesiczstkiAiBUIFigure);
            app.Obecnyczas0sLabel.Text = 'Obecny czas: 0 s';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FizykaDwieCzastkiOXOY

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DwieprzesuwajcesiczstkiAiBUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Inicjalizacja)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DwieprzesuwajcesiczstkiAiBUIFigure)
        end
    end
end